classdef image_filtering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConvolutionMenu               matlab.ui.container.Menu
        ImageSmoothingMenu            matlab.ui.container.Menu
        FreqdomainFilteringMenu       matlab.ui.container.Menu
        FreqdomainBrighteningMenu     matlab.ui.container.Menu
        NoiseFilteringMenu            matlab.ui.container.Menu
        PeriodicNoiseRestorationMenu  matlab.ui.container.Menu
        MotionBlurMenu                matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        Type2DropDown                 matlab.ui.control.DropDown
        TypeDropDown_2Label           matlab.ui.control.Label
        Type1DropDown                 matlab.ui.control.DropDown
        Input2EditField               matlab.ui.control.NumericEditField
        Input2EditFieldLabel          matlab.ui.control.Label
        Input1EditField               matlab.ui.control.NumericEditField
        Input1EditFieldLabel          matlab.ui.control.Label
        TypeDropDownLabel             matlab.ui.control.Label
        ExecuteButton                 matlab.ui.control.Button
        LogArea                       matlab.ui.control.TextArea
        SystemLogTextAreaLabel        matlab.ui.control.Label
        SelectedImageLabel            matlab.ui.control.Label
        BrowseButtonLabel             matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        InfoMask                      matlab.ui.control.Label
        InputMaskEditField            matlab.ui.control.EditField
        InputMaskEditFieldLabel       matlab.ui.control.Label
        ConfigLabel                   matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        ResultImage                   matlab.ui.control.Image
        ResultImageLabel              matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        InputImage                    matlab.ui.control.Image
        InputImageLabel               matlab.ui.control.Label
    end

    properties (Access = public)
        IMAGE_INPUT = [];
        IMAGE_RESULT = [];
        MASK_VALUE_STR = "";
        MASK_VALUE
        N_MASK
        LOG_STR = "";
    end
    
    methods (Access = public)
        function print_config_on_system_log(app)
            app.LogArea.Value = "";
            app.LOG_STR = "";
            if (app.Type1DropDown.Visible == "on")
                app.LOG_STR = app.LOG_STR + app.TypeDropDownLabel.Text + " Value: "+ app.Type1DropDown.Value + newline;
            end
            if (app.Type2DropDown.Visible == "on")
                app.LOG_STR = app.LOG_STR + app.TypeDropDown_2Label.Text + " Value: "+ app.Type2DropDown.Value + newline;
            end
            if (app.Input1EditField.Visible == "on")
                app.LOG_STR = app.LOG_STR + app.Input1EditFieldLabel.Text + " Value: "+ app.Input1EditField.Value + newline;
            end
            if (app.Input2EditField.Visible == "on")
                app.LOG_STR = app.LOG_STR + app.Input2EditFieldLabel.Text + " Value: "+ app.Input2EditField.Value + newline;
            end
            app.LogArea.Value = app.LOG_STR;
            drawnow;
        end

        function results = append_string_newline_and_print(app, str_initial, str_append)
            results = str_initial + newline + str_append;
            app.LogArea.Value = results;
            drawnow;
        end

        function display_image_on_ui(~, img, ui)
            if (size(img, 3) == 1)
                grayImage = img;
                image_to_display = cat(3, grayImage, grayImage, grayImage);
            else
                image_to_display = img;
            end
            ui.ImageSource = image_to_display;
        end


        function set_panel_config(app, config)
            if (ismember(1, config))
                app.InfoMask.Visible = "on";
                app.InputMaskEditField.Visible = "on";
                app.InputMaskEditFieldLabel.Visible = "on";
            else
                app.InfoMask.Visible = "off";
                app.InputMaskEditField.Visible = "off";
                app.InputMaskEditFieldLabel.Visible = "off";
            end

            if(ismember(2,config))
                app.Type1DropDown.Visible = "on";
                app.TypeDropDownLabel.Visible="on";
            else
                app.Type1DropDown.Visible = "off";
                app.TypeDropDownLabel.Visible="off";
            end

            if(ismember(3,config))
                app.Type2DropDown.Visible = "on";
                app.TypeDropDown_2Label.Visible="on";
            else
                app.Type2DropDown.Visible = "off";
                app.TypeDropDown_2Label.Visible="off";
            end


            if(ismember(4,config))
                app.Input1EditField.Visible = "on";
                app.Input1EditFieldLabel.Visible = "on";
            else
                app.Input1EditField.Visible = "off";
                app.Input1EditFieldLabel.Visible = "off";
            end

            if(ismember(5,config))
                app.Input2EditField.Visible = "on";
                app.Input2EditFieldLabel.Visible = "on";
            else
                app.Input2EditField.Visible = "off";
                app.Input2EditFieldLabel.Visible = "off";
            end

        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ConvolutionMenu
        function ConvolutionMenuSelected(app, event)
            app.ConfigLabel.Text = 'Convolution';
            set_panel_config(app,[1]);
        end

        % Menu selected function: ImageSmoothingMenu
        function ImageSmoothingMenuSelected(app, event)
            app.ConfigLabel.Text = 'Image Smoothing';
            set_panel_config(app,[2,3,4]);

            app.TypeDropDownLabel.Text = "Smoothing Domain";
            app.Type1DropDown.Items = ["Spatial", "Frequency"];
            app.Type1DropDown.Value = "Spatial";
            app.TypeDropDown_2Label.Text = "Smoothing Type";
            app.Type2DropDown.Items = ["Mean", "Gaussian"];
            app.Type2DropDown.Value = "Mean";

            app.Input1EditFieldLabel.Text = "n Mask";
            app.Input1EditField.Value = 1;
        end

        % Menu selected function: FreqdomainFilteringMenu
        function FreqdomainFilteringMenuSelected(app, event)
            app.ConfigLabel.Text = 'Freq-domain Filtering';

        end

        % Menu selected function: FreqdomainBrighteningMenu
        function FreqdomainBrighteningMenuSelected(app, event)
            app.ConfigLabel.Text = 'Freq-domain Brightening';

        end

        % Menu selected function: NoiseFilteringMenu
        function NoiseFilteringMenuSelected(app, event)
            app.ConfigLabel.Text = 'Noise Filtering';

        end

        % Menu selected function: PeriodicNoiseRestorationMenu
        function PeriodicNoiseRestorationMenuSelected(app, event)
            app.ConfigLabel.Text = 'Periodic Noise Restoration';

        end

        % Menu selected function: MotionBlurMenu
        function MotionBlurMenuSelected(app, event)
            app.ConfigLabel.Text = 'Motion Blur';

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
         
            % Open file dialog to browse for an image file
            [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            
            % If the user selected a file, display it in the UIAxes
            if ischar(fileName)
                % Full file path of the selected image
                fullFileName = fullfile(filePath, fileName);

                % Change label
                app.SelectedImageLabel.Text = fileName;
                app.IMAGE_INPUT= imread(fullFileName);

                display_image_on_ui(app, app.IMAGE_INPUT, app.InputImage);
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            addpath("functions\matrix\");
            addpath("functions\programs\");
            app.LOG_STR = ""; % Reset System Log
            app.LogArea.Value = "";

            if (isempty(app.IMAGE_INPUT))
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[FAILED] No image is inputted!");
                return;
            end

            if (app.ConfigLabel.Text == "Convolution")
                if (isempty(app.MASK_VALUE_STR))
                    app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[FAILED] Mask is empty!");
                    return;
                end

                [app.MASK_VALUE, app.N_MASK] = reshape_mask(app.MASK_VALUE_STR);
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[INITIATE] Convolution Process");
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[PROCESS] Process might take time. Please wait...");
                mask_str = "[MASK] " + display_mat(app.MASK_VALUE);
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR,  mask_str);
                app.IMAGE_RESULT = convolution(app.IMAGE_INPUT, app.MASK_VALUE, app.N_MASK);
                
                display_image_on_ui(app, app.IMAGE_RESULT, app.ResultImage);

            elseif (app.ConfigLabel.Text == "Image Smoothing")
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[INITIATE] Image Smoothing Process");
                app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[PROCESS] Process might take time. Please wait...");

                if (app.Type1DropDown.Value == "Spatial")
                    app.IMAGE_RESULT = image_smoothing_spatial(app.IMAGE_INPUT, app.Type2DropDown.Value, app.Input1EditField.Value, app.Input2EditField.Value);
                else % (app.Type1DropDown.Value == "Frequency")
                    app.IMAGE_RESULT = image_smoothing_frequency(app.IMAGE_INPUT, app.Type2DropDown.Value, app.Input1EditField.Value);
                end

                display_image_on_ui(app, app.IMAGE_RESULT, app.ResultImage);

            elseif (app.ConfigLabel.Text == "Freq-domain Filtering")
                app.LOG_STR = "[INITIATE] Freq-domain Filtering Process";
            elseif (app.ConfigLabel.Text == "Freq-domain Brightening")
                app.LOG_STR = "[INITIATE] Freq-domain Brightening Process";
            elseif (app.ConfigLabel.Text == "Noise Filtering")
                app.LOG_STR = "[INITIATE] Noise Filtering Process";
            elseif (app.ConfigLabel.Text == "Periodic Noise Restoration")
                app.LOG_STR = "[INITIATE] Periodic Noise Restoration Process";
            else % (app.ConfigLabel.Text == "Motion Blur")
                app.LOG_STR = "[INITIATE] Motion Blur Process";
            end

            app.LOG_STR = append_string_newline_and_print(app, app.LOG_STR, "[FINISHED] Process has finished!");
        end

        % Value changed function: InputMaskEditField
        function InputMaskEditFieldValueChanged(app, event)
            addpath("functions\utils\");
            app.MASK_VALUE_STR = app.InputMaskEditField.Value;
        end

        % Value changed function: Type1DropDown
        function Type1DropDownValueChanged(app, event)
           
            % Handles
            if (app.ConfigLabel.Text == "Image Smoothing" && app.Type1DropDown.Value == "Spatial")
                set_panel_config(app,[2,3,4]);
                app.TypeDropDown_2Label.Text = "Smoothing Type";
                app.Type2DropDown.Items = ["Mean", "Gaussian"];
                app.Type2DropDown.Value = "Mean";

                app.Input1EditFieldLabel.Text = "n Mask";
                app.Input1EditField.Value = 1;

            elseif (app.ConfigLabel.Text == "Image Smoothing" && app.Type1DropDown.Value == "Frequency")
                set_panel_config(app,[2,3,4]);
                app.TypeDropDown_2Label.Text = "Frequency Type";
                app.Type2DropDown.Items = ["ILPF", "GLPF", "BLPF", "IHPF", "GHPF", "BHPF", "Homomorphic"];
                app.Type2DropDown.Value = "ILPF";

                app.Input1EditFieldLabel.Text = "Cutoff Frequency";
                app.Input1EditField.Value= 0;
            end

            print_config_on_system_log(app);
        end

        % Value changed function: Type2DropDown
        function Type2DropDownValueChanged(app, event)
            if (app.ConfigLabel.Text == "Image Smoothing" && app.Type1DropDown.Value == "Spatial" && app.Type2DropDown.Value == "Mean")
                set_panel_config(app, [2,3,4])
            elseif (app.ConfigLabel.Text == "Image Smoothing" && app.Type1DropDown.Value == "Spatial" && app.Type2DropDown.Value == "Gaussian")
                set_panel_config(app, [2,3,4,5])
                app.Input2EditFieldLabel.Text = "Sigma";
                app.Input2EditField.Value = 0;
            end
            
            print_config_on_system_log(app);
        end

        % Value changed function: Input1EditField
        function Input1EditFieldValueChanged(app, event)

            print_config_on_system_log(app);
        end

        % Value changed function: Input2EditField
        function Input2EditFieldValueChanged(app, event)

            print_config_on_system_log(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConvolutionMenu = uimenu(app.UIFigure);
            app.ConvolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @ConvolutionMenuSelected, true);
            app.ConvolutionMenu.Text = 'Convolution';
            app.ImageSmoothingMenu = uimenu(app.UIFigure);
            app.ImageSmoothingMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageSmoothingMenuSelected, true);
            app.ImageSmoothingMenu.Text = 'Image Smoothing';
            app.FreqdomainFilteringMenu = uimenu(app.UIFigure);
            app.FreqdomainFilteringMenu.MenuSelectedFcn = createCallbackFcn(app, @FreqdomainFilteringMenuSelected, true);
            app.FreqdomainFilteringMenu.Text = 'Freq-domain Filtering';
            app.FreqdomainBrighteningMenu = uimenu(app.UIFigure);
            app.FreqdomainBrighteningMenu.MenuSelectedFcn = createCallbackFcn(app, @FreqdomainBrighteningMenuSelected, true);
            app.FreqdomainBrighteningMenu.Text = 'Freq-domain Brightening';
            app.NoiseFilteringMenu = uimenu(app.UIFigure);
            app.NoiseFilteringMenu.MenuSelectedFcn = createCallbackFcn(app, @NoiseFilteringMenuSelected, true);
            app.NoiseFilteringMenu.Text = 'Noise Filtering';
            app.PeriodicNoiseRestorationMenu = uimenu(app.UIFigure);
            app.PeriodicNoiseRestorationMenu.MenuSelectedFcn = createCallbackFcn(app, @PeriodicNoiseRestorationMenuSelected, true);
            app.PeriodicNoiseRestorationMenu.Text = 'Periodic Noise Restoration';
            app.MotionBlurMenu = uimenu(app.UIFigure);
            app.MotionBlurMenu.MenuSelectedFcn = createCallbackFcn(app, @MotionBlurMenuSelected, true);
            app.MotionBlurMenu.Text = 'Motion Blur';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.InputImageLabel = uilabel(app.GridLayout3);
            app.InputImageLabel.Text = 'Input Image';
            app.InputImage = uiimage(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.ResultImageLabel = uilabel(app.GridLayout4);
            app.ResultImageLabel.Text = 'Result Image';
            app.ResultImage = uiimage(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.ConfigLabel = uilabel(app.GridLayout5);
            app.ConfigLabel.Text = 'Convolution';
            app.InputMaskEditFieldLabel = uilabel(app.GridLayout5);
            app.InputMaskEditFieldLabel.Text = 'Input Mask';
            app.InputMaskEditField = uieditfield(app.GridLayout5, 'text');
            app.InputMaskEditField.ValueChangedFcn = createCallbackFcn(app, @InputMaskEditFieldValueChanged, true);
            app.InfoMask = uilabel(app.GridLayout5);
            app.InfoMask.Text = 'Seperated by space e.g.: 0 1 0 1 2 1 0 1 0';
            app.BrowseButton = uibutton(app.GridLayout5, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.BrowseButtonLabel = uilabel(app.GridLayout5);
            app.BrowseButtonLabel.Text = 'Input Image';
            app.SelectedImageLabel = uilabel(app.GridLayout5);
            app.SelectedImageLabel.Text = 'No image has been chosen';
            app.SystemLogTextAreaLabel = uilabel(app.GridLayout5);
            app.SystemLogTextAreaLabel.Text = 'System Log';
            app.LogArea = uitextarea(app.GridLayout5);
            app.ExecuteButton = uibutton(app.GridLayout5, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.TypeDropDownLabel = uilabel(app.GridLayout5);
            app.TypeDropDownLabel.Visible = 'off';
            app.TypeDropDownLabel.Text = 'Type';
            app.Input1EditFieldLabel = uilabel(app.GridLayout5);
            app.Input1EditFieldLabel.Visible = 'off';
            app.Input1EditFieldLabel.Text = 'Input 1';
            app.Input1EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Input1EditField.ValueChangedFcn = createCallbackFcn(app, @Input1EditFieldValueChanged, true);
            app.Input1EditField.Visible = 'off';
            app.Input2EditFieldLabel = uilabel(app.GridLayout5);
            app.Input2EditFieldLabel.Visible = 'off';
            app.Input2EditFieldLabel.Text = 'Input 2';
            app.Input2EditField = uieditfield(app.GridLayout5, 'numeric');
            app.Input2EditField.ValueChangedFcn = createCallbackFcn(app, @Input2EditFieldValueChanged, true);
            app.Input2EditField.Visible = 'off';
            app.Type1DropDown = uidropdown(app.GridLayout5);
            app.Type1DropDown.ValueChangedFcn = createCallbackFcn(app, @Type1DropDownValueChanged, true);
            app.Type1DropDown.Visible = 'off';
            app.TypeDropDown_2Label = uilabel(app.GridLayout5);
            app.TypeDropDown_2Label.Visible = 'off';
            app.TypeDropDown_2Label.Text = 'Type';
            app.Type2DropDown = uidropdown(app.GridLayout5);
            app.Type2DropDown.ValueChangedFcn = createCallbackFcn(app, @Type2DropDownValueChanged, true);
            app.Type2DropDown.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_filtering

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end